classdef dipole_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DesignofDipoleAntenna17BEC004KASHYAPADODARIYALabel  matlab.ui.control.Label
        PatternButtonGroup    matlab.ui.container.ButtonGroup
        DirectivityButton     matlab.ui.control.RadioButton
        PowerButton           matlab.ui.control.RadioButton
        FieldButton           matlab.ui.control.RadioButton
        ParameterButtonGroup  matlab.ui.container.ButtonGroup
        ImpedanceButton       matlab.ui.control.RadioButton
        S11Button             matlab.ui.control.RadioButton
        SimulationButton      matlab.ui.control.Button
        FreqEditFieldLabel    matlab.ui.control.Label
        FreqEditField         matlab.ui.control.NumericEditField
        LEditFieldLabel       matlab.ui.control.Label
        LEditField            matlab.ui.control.NumericEditField
        WEditFieldLabel       matlab.ui.control.Label
        WEditField            matlab.ui.control.NumericEditField
        FstartEditFieldLabel  matlab.ui.control.Label
        FstartEditField       matlab.ui.control.NumericEditField
        FendEditFieldLabel    matlab.ui.control.Label
        FendEditField         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FreqEditField
        function FreqEditFieldValueChanged(app, event)
        
            
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
          
            
        end

        % Value changed function: WEditField
        function WEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: FstartEditField
        function FstartEditFieldValueChanged(app, event)
            
        end

        % Value changed function: FendEditField
        function FendEditFieldValueChanged(app, event)
            
        end

        % Selection changed function: PatternButtonGroup
        function PatternButtonGroupSelectionChanged(app, event)


        end

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
            F = app.FreqEditField.Value;
            lam = 3e8/F;
         
            L= lam*0.5;
            app.LEditField.Value = L;
            W= L/80;
            app.WEditField.Value =W;
            d=dipole('Length',L,'Width',W);
            Fst = app.FstartEditField.Value;
            Fend= app.FendEditField.Value;
            Fs= (Fend-Fst)/100;
            
            
            if app.DirectivityButton.Value == 1
                a = pattern(d,F,'Type','Directivity');
                subplot(2,1,1); 
                polarpattern(a);
            elseif app.PowerButton.Value == 1
                a = pattern(d,F,'Type','Power');
                subplot(2,1,1); 
                polarpattern(a);
            elseif app.FieldButton.Value == 1
                a = pattern(d,F,'Type','efield');
                subplot(2,1,1); 
                polarpattern(a);
                
            end
            if app.ImpedanceButton.Value ==1
                b = impedance(d,Fst:Fs:Fend);
                t=0.5e9:1e7:1.5e9;
                subplot(2,1,2); 
                plot(t,b);
            elseif app.S11Button.Value ==1
                s= sparameters(d,Fst:Fs:Fend);
                subplot(2,1,2); 
                rfplot(s);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DesignofDipoleAntenna17BEC004KASHYAPADODARIYALabel = uilabel(app.UIFigure);
            app.DesignofDipoleAntenna17BEC004KASHYAPADODARIYALabel.Text = {'Design of Dipole Antenna'; '17BEC004           KASHYAP ADODARIYA'};
            app.PatternButtonGroup = uibuttongroup(app.UIFigure);
            app.PatternButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PatternButtonGroupSelectionChanged, true);
            app.PatternButtonGroup.Title = 'Pattern';
            app.DirectivityButton = uiradiobutton(app.PatternButtonGroup);
            app.DirectivityButton.Text = 'Directivity';
            app.DirectivityButton.Value = true;
            app.PowerButton = uiradiobutton(app.PatternButtonGroup);
            app.PowerButton.Text = 'Power';
            app.FieldButton = uiradiobutton(app.PatternButtonGroup);
            app.FieldButton.Text = 'Field';
            app.ParameterButtonGroup = uibuttongroup(app.UIFigure);
            app.ParameterButtonGroup.Title = 'Parameter';
            app.ImpedanceButton = uiradiobutton(app.ParameterButtonGroup);
            app.ImpedanceButton.Text = 'Impedance';
            app.ImpedanceButton.Value = true;
            app.S11Button = uiradiobutton(app.ParameterButtonGroup);
            app.S11Button.Text = 'S11';
            app.SimulationButton = uibutton(app.UIFigure, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.FreqEditFieldLabel = uilabel(app.UIFigure);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.FreqEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreqEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEditFieldValueChanged, true);
            app.FreqEditField.Value = 1000000000;
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.UIFigure, 'numeric');
            app.WEditField.ValueChangedFcn = createCallbackFcn(app, @WEditFieldValueChanged, true);
            app.FstartEditFieldLabel = uilabel(app.UIFigure);
            app.FstartEditFieldLabel.Text = 'F start';
            app.FstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.FstartEditField.ValueChangedFcn = createCallbackFcn(app, @FstartEditFieldValueChanged, true);
            app.FstartEditField.Value = 500000000;
            app.FendEditFieldLabel = uilabel(app.UIFigure);
            app.FendEditFieldLabel.Text = 'F end';
            app.FendEditField = uieditfield(app.UIFigure, 'numeric');
            app.FendEditField.ValueChangedFcn = createCallbackFcn(app, @FendEditFieldValueChanged, true);
            app.FendEditField.Value = 1500000000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dipole_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end